classdef Capture_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        RGBPreviewTab                 matlab.ui.container.Tab
        ExposureEditFieldLabel        matlab.ui.control.Label
        ExposureEditField             matlab.ui.control.NumericEditField
        DisplayBinningEditFieldLabel  matlab.ui.control.Label
        DisplayBinningEditField       matlab.ui.control.NumericEditField
        CorrectcolorCheckBox          matlab.ui.control.CheckBox
        UIAxes_Cam1                   matlab.ui.control.UIAxes
        UIAxes_Cam2                   matlab.ui.control.UIAxes
        UIAxes_Cam3                   matlab.ui.control.UIAxes
        UIAxes_Cam4                   matlab.ui.control.UIAxes
        UIAxes_Cam5                   matlab.ui.control.UIAxes
        UIAxes_Cam6                   matlab.ui.control.UIAxes
        UIAxes_Cam7                   matlab.ui.control.UIAxes
        ConnectionStatusPanel         matlab.ui.container.Panel
        Switch_AllCam                 matlab.ui.control.Switch
        Cam1Lamp                      matlab.ui.control.Lamp
        Camera1Label                  matlab.ui.control.Label
        Cam2Lamp                      matlab.ui.control.Lamp
        Camera2Label                  matlab.ui.control.Label
        Cam3Lamp                      matlab.ui.control.Lamp
        Camera3Label                  matlab.ui.control.Label
        Cam4Lamp                      matlab.ui.control.Lamp
        Camera4Label                  matlab.ui.control.Label
        Cam5Lamp                      matlab.ui.control.Lamp
        Camera5Label                  matlab.ui.control.Label
        Cam6Lamp                      matlab.ui.control.Lamp
        Camera6Label                  matlab.ui.control.Label
        Cam7Lamp                      matlab.ui.control.Lamp
        Camera7Label                  matlab.ui.control.Label
        LocationButton                matlab.ui.control.Button
        FolderEditFieldLabel          matlab.ui.control.Label
        FolderEditField               matlab.ui.control.EditField
        File_NameEditFieldLabel       matlab.ui.control.Label
        File_NameEditField            matlab.ui.control.EditField
        CaptureButton                 matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        vid;
        src;
        cam_order;
        Img_loading;
    end
    
   
    properties (Access = public)
        ImageData;
        preview_bin;
        save_bin;
        exposuretime;
        cam_switch;
        ccm_data;
        saving_dir;
        saving_name;
        saving_fmt;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            imaqreset;
            
            app.ConnectionStatusPanel.HandleVisibility = 'Off';
            
            [app.vid.c1, app.vid.c2, app.vid.c3, app.vid.c4, app.vid.c5, app.vid.c6, app.vid.c7] = deal([]);
            [app.src.c1, app.src.c2, app.src.c3, app.src.c4, app.src.c5, app.src.c6, app.src.c7] = deal([]);
            app.cam_order = [2,6,3,7,5,1,4];
            
            sz = [1024,1296,3];
            app.ImageData = repmat({zeros(sz)},7,1);
            app.preview_bin = 4;
            app.save_bin = 2;
            app.cam_switch = 'Off';
            app.Switch_AllCam.Value = app.cam_switch;
            app.exposuretime = 1000; % ms
            app.ccm_data = load('ccm.mat');
            app.saving_dir = pwd;
            app.saving_fmt = '.png';
            date_str = datestr(datetime,29);
            app.saving_name = date_str;
            
            app.FolderEditField.Value = pwd;
            app.File_NameEditField.Value = date_str;
            
            app.DisplayBinningEditField.Value = app.preview_bin;
            app.ExposureEditField.Value = app.exposuretime;
            drawnow;
            
            info = imaqhwinfo('gentl');
            device_ids = info.DeviceIDs;
            if length(device_ids)<7
                errordlg(['Only ', num2str(length(device_ids)),' Cameras loaded'], 'Be Aware!!!')
                drawnow limitrate
            end
            
            app.ConnectionStatusPanel.HandleVisibility = 'On';
            
        end

        % Value changed function: Switch_AllCam
        function Switch_AllCamValueChanged(app, event)
            app.cam_switch = app.Switch_AllCam.Value;
            
            if (strcmp(app.cam_switch, 'On'))
                
                app.Img_loading = imread('loading_img.jpg');
                himage = [];
                % connect all cameras
                Vid = [];
                Src = [];
                
                for i = 1:7
                    % display loading image on axes(i)
                    eval(['himage = imshow(app.Img_loading, "Parent",app.UIAxes_Cam',num2str(i),');']);
                    drawnow limitrate;
                    
                    try
                        Vid = videoinput('gentl',app.cam_order(i),'RGB8Packed');
                        Vid.FramesperTrigger = 1;
                        triggerconfig(Vid, 'hardware', 'DeviceSpecific');
                        
                        Src = getselectedsource(Vid);
                        switch app.preview_bin
                            case 4 
                                Src.BinningHorizontal = 'BinningHorizontal4';
                                Src.BinningVertical = 'BinningVertical4';
                            case 2
                                Src.BinningHorizontal = 'BinningHorizontal2';
                                Src.BinningVertical = 'BinningVertical2';
                            case 1
                                Src.BinningHorizontal = 'BinningHorizontal1';
                                Src.BinningVertical = 'BinningVertical1';
                            otherwise
                                Src.BinningHorizontal = 'BinningHorizontal4';
                                Src.BinningVertical = 'BinningVertical4';
                        end
                        
                        Src.ExposureTime = app.exposuretime;
                        Src.GammaSelector = 'sRGB';
                        Src.GainAuto = 'Off';
                        Src.BalanceRatioSelector = 'Red';
%                         Src.BalanceRatio = 1500;
                        
                        
                        % preview
                        preview(Vid,himage);
                        drawnow nocallbacks
                    
                        
                        % turn button  color green
                        eval(['app.Cam',num2str(i),'Lamp.Color = [0,1,0];']);
                        
                        % save vid and src items
                        eval(['app.vid.c',num2str(i),' = Vid;']);
                        eval(['app.src.c',num2str(i),' = Src;']);
                        drawnow nocallbacks
                    catch
                        warndlg(['Camera_',num2str(i),' ID Not found'], 'Warning!!!');
                    end
                end
                
            else
                
                for i = 1:7
                    % disconnect all cameras
                    try
                        eval(['stoppreview(app.vid.c',num2str(i),')']);
                    end
                    
                    %turn all the lights off
                    eval(['app.Cam',num2str(i),'Lamp.Color = [0.65,0.65,0.65];']);
                    drawnow
                end
                imaqreset;
            end
            
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)
            value= app.ExposureEditField.Value;
            app.exposuretime = value;
            for i = 1:7
                Src = eval(['app.src.c',num2str(i)]);
                try
                    Src.ExposureTime = app.exposuretime;
                catch
                    warndlg(['Cam_',nun2str(i),' exposure not set']);
                end
            end
        end

        % Value changed function: DisplayBinningEditField
        function DisplayBinningEditFieldValueChanged(app, event)
            value = app.DisplayBinningEditField.Value;
            
            for i = 1:7
                Src = eval(['app.src.c',num2str(i)]);
                switch value
                    case 4 
                        Src.BinningHorizontal = 'BinningHorizontal4';
                        Src.BinningVertical = 'BinningVertical4';
                    case 2
                        Src.BinningHorizontal = 'BinningHorizontal2';
                        Src.BinningVertical = 'BinningVertical2';
                    case 1
                        Src.BinningHorizontal = 'BinningHorizontal1';
                        Src.BinningVertical = 'BinningVertical1';
                    otherwise
                        Src.BinningHorizontal = 'BinningHorizontal4';
                        Src.BinningVertical = 'BinningVertical4';
                end
            end
            drawnow;
        end

        % Callback function
        function CorrectColorButtonPushed(app, event)
            
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            % collect data from cameras
            Data = app.ImageData;
            
            fg = waitbar(0,'Please wait...');
            
            for i = 1:7
                Vid = eval(['app.vid.c',num2str(i),';']);
                Src = eval(['app.src.c',num2str(i)]);
                
                % stop preview
                stoppreview(Vid);
                
                % change binning
                Src.BinningHorizontal = 'BinningHorizontal2';
                Src.BinningVertical = 'BinningVertical2';
                
                % capture image
                Im = (getsnapshot(Vid));
                Data{i} = Im;
                
                waitbar(i/7,fg,['Collecting', num2str(i+1),' out of 7'])
                drawnow nocallbacks
            end
            delete(fg);
            
            app.ImageData = Data;
            % Display images
            for i  = 1: length(Data)
                eval(['imshow(Data{i}, "Parent",app.UIAxes_Cam',num2str(i),');']);
            end
            drawnow limitrate
            
            pause(2)% wait for 2 seconds
            
            % show live view again
              
                
            for i = 1:7
                % display loading image on axes(i)
                eval(['himage = imshow(app.Img_loading, "Parent",app.UIAxes_Cam',num2str(i),');']);
                drawnow limitrate;
                
                try
                    Vid = eval(['app.vid.c',num2str(i),';']);
                    Src = eval(['app.src.c',num2str(i)]);
                    
                    % change back to dispaly binning
                    switch app.preview_bin
                        case 4 
                            Src.BinningHorizontal = 'BinningHorizontal4';
                            Src.BinningVertical = 'BinningVertical4';
                        case 2
                            Src.BinningHorizontal = 'BinningHorizontal2';
                            Src.BinningVertical = 'BinningVertical2';
                        case 1
                            Src.BinningHorizontal = 'BinningHorizontal1';
                            Src.BinningVertical = 'BinningVertical1';
                        otherwise
                            Src.BinningHorizontal = 'BinningHorizontal4';
                            Src.BinningVertical = 'BinningVertical4';
                    end
                    % preview
                    preview(Vid,himage);
                    
                    drawnow nocallbacks;
                catch
                    warndlg(['Camera_',num2str(i),' ID Not found'], 'Warning!!!');
                end
            end
        end

        % Value changed function: CorrectcolorCheckBox
        function CorrectcolorCheckBoxValueChanged(app, event)
            value = app.CorrectcolorCheckBox.Value;
            if value == 1
                % color correction
                ccm = app.ccm_data;
                Data = app.ImageData;
                for i = 1:7
                    a= 0;
                end
            end
        end

        % Button pushed function: LocationButton
        function LocationButtonPushed(app, event)
            app.saving_dir = uigetdir;
            app.FolderEditField.Value = app.saving_dir;
        end

        % Value changed function: File_NameEditField
        function File_NameEditFieldValueChanged(app, event)
            value = app.File_NameEditField.Value;
            app.saving_name = value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Data = app.ImageData;
            
            sav_folder = [app.saving_dir,'\',app.saving_name];
            
            try
                mkdir(sav_folder)
            catch
                msgbox('folder already exists');
            end
            
            fg = waitbar(0,'Please wait...');
            for i = 1:7
                Im = Data{i};
                imwrite(Im,[sav_folder,'\Img_',num2str(i),app.saving_fmt]);
                waitbar(i/7,fg,['Saving image ', num2str(i),' out of 7'])
            end
            delete(fg)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            imaqreset;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'atitle.PNG';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RGBPreviewTab = uitab(app.TabGroup);
            app.RGBPreviewTab.Title = 'RGB Preview';
            app.ExposureEditFieldLabel = uilabel(app.RGBPreviewTab);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.RGBPreviewTab, 'numeric');
            app.ExposureEditField.ValueDisplayFormat = '%.0f';
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.DisplayBinningEditFieldLabel = uilabel(app.RGBPreviewTab);
            app.DisplayBinningEditFieldLabel.Enable = 'off';
            app.DisplayBinningEditFieldLabel.Text = 'Display Binning';
            app.DisplayBinningEditField = uieditfield(app.RGBPreviewTab, 'numeric');
            app.DisplayBinningEditField.ValueDisplayFormat = '%.0f';
            app.DisplayBinningEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayBinningEditFieldValueChanged, true);
            app.DisplayBinningEditField.Editable = 'off';
            app.DisplayBinningEditField.Enable = 'off';
            app.CorrectcolorCheckBox = uicheckbox(app.RGBPreviewTab);
            app.CorrectcolorCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectcolorCheckBoxValueChanged, true);
            app.CorrectcolorCheckBox.Text = 'Correct color';
            app.UIAxes_Cam1 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam1, '1')
            app.UIAxes_Cam1.XTickLabel = {''; ''; ''};
            app.UIAxes_Cam1.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_Cam2 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam2, '2')
            app.UIAxes_Cam2.XTickLabel = {''; ''; ''};
            app.UIAxes_Cam2.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_Cam3 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam3, '3')
            app.UIAxes_Cam3.XTickLabel = {''; ''; ''};
            app.UIAxes_Cam3.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_Cam4 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam4, '4')
            app.UIAxes_Cam4.XTickLabel = {''; ''; ''};
            app.UIAxes_Cam4.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_Cam5 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam5, '5')
            app.UIAxes_Cam5.XTickLabel = {''; ''; ''};
            app.UIAxes_Cam5.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_Cam6 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam6, '6')
            app.UIAxes_Cam6.XTickLabel = {''; ''; ''};
            app.UIAxes_Cam6.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_Cam7 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam7, '7')
            app.UIAxes_Cam7.XTickLabel = {''; ''; ''};
            app.UIAxes_Cam7.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.ConnectionStatusPanel = uipanel(app.UIFigure);
            app.ConnectionStatusPanel.Title = 'Connection Status ';
            app.Switch_AllCam = uiswitch(app.ConnectionStatusPanel, 'slider');
            app.Switch_AllCam.ValueChangedFcn = createCallbackFcn(app, @Switch_AllCamValueChanged, true);
            app.Cam1Lamp = uilamp(app.ConnectionStatusPanel);
            app.Camera1Label = uilabel(app.ConnectionStatusPanel);
            app.Camera1Label.Text = 'Camera 1';
            app.Cam2Lamp = uilamp(app.ConnectionStatusPanel);
            app.Camera2Label = uilabel(app.ConnectionStatusPanel);
            app.Camera2Label.Text = 'Camera 2';
            app.Cam3Lamp = uilamp(app.ConnectionStatusPanel);
            app.Camera3Label = uilabel(app.ConnectionStatusPanel);
            app.Camera3Label.Text = 'Camera 3';
            app.Cam4Lamp = uilamp(app.ConnectionStatusPanel);
            app.Camera4Label = uilabel(app.ConnectionStatusPanel);
            app.Camera4Label.Text = 'Camera 4';
            app.Cam5Lamp = uilamp(app.ConnectionStatusPanel);
            app.Camera5Label = uilabel(app.ConnectionStatusPanel);
            app.Camera5Label.Text = 'Camera 5';
            app.Cam6Lamp = uilamp(app.ConnectionStatusPanel);
            app.Camera6Label = uilabel(app.ConnectionStatusPanel);
            app.Camera6Label.Text = 'Camera 6';
            app.Cam7Lamp = uilamp(app.ConnectionStatusPanel);
            app.Camera7Label = uilabel(app.ConnectionStatusPanel);
            app.Camera7Label.Text = 'Camera 7';
            app.LocationButton = uibutton(app.UIFigure, 'push');
            app.LocationButton.ButtonPushedFcn = createCallbackFcn(app, @LocationButtonPushed, true);
            app.LocationButton.Icon = 'folder.PNG';
            app.LocationButton.Text = 'Location';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.File_NameEditFieldLabel = uilabel(app.UIFigure);
            app.File_NameEditFieldLabel.Text = 'File_Name';
            app.File_NameEditField = uieditfield(app.UIFigure, 'text');
            app.File_NameEditField.ValueChangedFcn = createCallbackFcn(app, @File_NameEditFieldValueChanged, true);
            app.File_NameEditField.Value = 'test';
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Icon = 'OkB.PNG';
            app.CaptureButton.Text = 'Capture';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save_icon.png';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Capture_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end