classdef HTPP3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConnectionStatusPanel    matlab.ui.container.Panel
        Switch_Cam1              matlab.ui.control.Switch
        Cam1Lamp                 matlab.ui.control.Lamp
        DisconnectedLabel        matlab.ui.control.Label
        Switch_Cam2              matlab.ui.control.Switch
        Cam2Lamp                 matlab.ui.control.Lamp
        DisconnectedLabel_2      matlab.ui.control.Label
        Switch_Cam3              matlab.ui.control.Switch
        Cam3Lamp                 matlab.ui.control.Lamp
        Switch_Cam4              matlab.ui.control.Switch
        Cam4Lamp                 matlab.ui.control.Lamp
        Disconnect3Label_3       matlab.ui.control.Label
        Disconnect4Label_4       matlab.ui.control.Label
        Switch_Cam5              matlab.ui.control.Switch
        Disconnected5Label_5     matlab.ui.control.Label
        Cam5Lamp                 matlab.ui.control.Lamp
        Switch_Cam6              matlab.ui.control.Switch
        Disconnected6Label_6     matlab.ui.control.Label
        Cam6Lamp                 matlab.ui.control.Lamp
        Switch_Cam7              matlab.ui.control.Switch
        Disconnected7Label_7     matlab.ui.control.Label
        Cam7Lamp                 matlab.ui.control.Lamp
        TabGroup                 matlab.ui.container.TabGroup
        RGBPreviewTab            matlab.ui.container.Tab
        ExposureEditFieldLabel   matlab.ui.control.Label
        ExposureEditField        matlab.ui.control.NumericEditField
        ColorCorrectionButton    matlab.ui.control.Button
        UIAxes_Cam1              matlab.ui.control.UIAxes
        UIAxes_Cam2              matlab.ui.control.UIAxes
        UIAxes_Cam3              matlab.ui.control.UIAxes
        UIAxes_Cam4              matlab.ui.control.UIAxes
        UIAxes_Cam5              matlab.ui.control.UIAxes
        UIAxes_Cam6              matlab.ui.control.UIAxes
        UIAxes_Cam7              matlab.ui.control.UIAxes
        Image                    matlab.ui.control.Image
        CapturingImagePanel      matlab.ui.container.Panel
        FolderEditFieldLabel     matlab.ui.control.Label
        FolderEditField          matlab.ui.control.EditField
        LocationButton           matlab.ui.control.Button
        File_NameEditFieldLabel  matlab.ui.control.Label
        File_NameEditField       matlab.ui.control.EditField
        Number_SEditFieldLabel   matlab.ui.control.Label
        Number_SEditField        matlab.ui.control.NumericEditField
        Button_2                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
       vid_cam1
       src_cam1  
       vid_cam2
       src_cam2
       vid_cam3
       src_cam3
       vid_cam4
       src_cam4
       vid_cam5
       src_cam5
       vid_cam6
       src_cam6
       vid_cam7
       src_cam7
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
               global st1 st2 st3 st4 st5 st6 st7
             imaqreset;
             [app.vid_cam1,app.vid_cam2,app.vid_cam3,app.vid_cam4,app.vid_cam5,app.vid_cam6,app.vid_cam7] =deal([]);           
            app.Cam1Lamp.Color= [0.65,0.65,0.65]; 
            app.vid_cam1 = videoinput('gentl',1,'RGB8Packed');
            app.src_cam1 = getselectedsource(app.vid_cam1);
            app.src_cam1.BinningHorizontal = 'BinningHorizontal2';
            app.src_cam1.BinningVertical = 'BinningVertical2';
            app.vid_cam1.FramesperTrigger = 1;
            triggerconfig(app.vid_cam1, 'hardware', 'DeviceSpecific');
%               st = getsnapshot(app.vid_cam1);
%              preview(app.vid_cam1)            
            app.Cam2Lamp.Color= [0.65,0.65,0.65]; 
            app.vid_cam2 = videoinput('gentl',2,'RGB8Packed');
            app.src_cam2 = getselectedsource(app.vid_cam2);
            app.src_cam2.BinningHorizontal = 'BinningHorizontal2';
            app.src_cam2.BinningVertical = 'BinningVertical2';
            app.vid_cam2.FramesperTrigger = 1;
            triggerconfig(app.vid_cam2, 'hardware', 'DeviceSpecific');
%               st2 = getsnapshot(app.vid_cam2);
%                preview(app.vid_cam2)    
            app.Cam3Lamp.Color= [0.65,0.65,0.65]; 
            app.vid_cam3 = videoinput('gentl',3,'RGB8Packed');
            app.src_cam3 = getselectedsource(app.vid_cam3);
            app.src_cam3.BinningHorizontal = 'BinningHorizontal2';
            app.src_cam3.BinningVertical = 'BinningVertical2';
            app.vid_cam3.FramesperTrigger = 1;
            triggerconfig(app.vid_cam3, 'hardware', 'DeviceSpecific');
%               st2 = getsnapshot(app.vid_cam2);
%                preview(app.vid_cam2)
            app.Cam4Lamp.Color= [0.65,0.65,0.65]; 
            app.vid_cam4 = videoinput('gentl',4,'RGB8Packed');
            app.src_cam4 = getselectedsource(app.vid_cam4);
            app.src_cam4.BinningHorizontal = 'BinningHorizontal2';
            app.src_cam4.BinningVertical = 'BinningVertical2';
            app.vid_cam4.FramesperTrigger = 1;
            triggerconfig(app.vid_cam4, 'hardware', 'DeviceSpecific');
            
%               st2 = getsnapshot(app.vid_cam2);
%                preview(app.vid_cam2)
            app.Cam5Lamp.Color= [0.65,0.65,0.65]; 
            app.vid_cam5 = videoinput('gentl',5,'RGB8Packed');
            app.src_cam5 = getselectedsource(app.vid_cam5);
            app.src_cam5.BinningHorizontal = 'BinningHorizontal2';
            app.src_cam5.BinningVertical = 'BinningVertical2';
            app.vid_cam5.FramesperTrigger = 1;
            triggerconfig(app.vid_cam5, 'hardware', 'DeviceSpecific');
            
            app.Cam6Lamp.Color= [0.65,0.65,0.65]; 
            app.vid_cam6 = videoinput('gentl',6,'RGB8Packed');
            app.src_cam6 = getselectedsource(app.vid_cam6);
            app.src_cam6.BinningHorizontal = 'BinningHorizontal2';
            app.src_cam6.BinningVertical = 'BinningVertical2';
            app.vid_cam6.FramesperTrigger = 1;
            triggerconfig(app.vid_cam6, 'hardware', 'DeviceSpecific');                       
            
            app.Cam7Lamp.Color= [0.65,0.65,0.65]; 
            app.vid_cam7 = videoinput('gentl',7,'RGB8Packed');
            app.src_cam7 = getselectedsource(app.vid_cam7);
            app.src_cam7.BinningHorizontal = 'BinningHorizontal2';
            app.src_cam7.BinningVertical = 'BinningVertical2';
            app.vid_cam7.FramesperTrigger = 1;
            triggerconfig(app.vid_cam7, 'hardware', 'DeviceSpecific');            
        end

        % Value changed function: Switch_Cam1
        function Switch_Cam1ValueChanged(app, event)
              global st1
            Value = app.Switch_Cam1.Value;
            switch Value
                case 'On'
%                     app.Cam1Lamp.Color = [0.5 0.5 0.5];
%                     vid_top = videoinput('gentl', 1, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');                   
                    % These lines set proper aspect ratio
                    img_width=app.src_cam1.AutoFunctionAOIWidth;
                    img_height=app.src_cam1.AutoFunctionAOIHeight;
                    app.UIAxes_Cam1.XLim = [0,img_width];                    
                    app.UIAxes_Cam1.YLim = [0,img_height];                    
                    app.UIAxes_Cam1.XTick = [];                    
                    app.UIAxes_Cam1.YTick = [];
                    pbaspect(app.UIAxes_Cam1,[img_width,img_height,1])
%                     pause(2)
%                     hImage1 = image(zeros(size(st1)), 'Parent',app.UIAxes_Cam1);
%                     preview(app.vid_cam1, hImage1);
%                     drawnow;
%                      start(app.vid_cam1)
                    st1=getsnapshot(app.vid_cam1);
                    imshow(st1,'parent',app.UIAxes_Cam1);                    
%                     delete(vid_top)
                    app.Cam1Lamp.Color = [00,1.00,0.00] ;
                    app.DisconnectedLabel.Text = 'Connected';                  
                case 'Off'
                    st1 = zeros(2048,2592);
                    imshow(st1,'parent',app.UIAxes_Cam1);
                    app.Cam1Lamp.Color = [0.65,0.65,0.65];
                     app.DisconnectedLabel.Text = 'Disconnected';
%                      stoppreview(app.vid_cam2);
            end
        end

        % Value changed function: Switch_Cam2
        function Switch_Cam2ValueChanged(app, event)
              global st2
            Value = app.Switch_Cam2.Value;
            switch Value
                case 'On'
%                     app.Cam1Lamp.Color = [0.5 0.5 0.5];
%                     vid_top = videoinput('gentl', 1, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');                  
                    % These lines set proper aspect ratio
                    img_width=app.src_cam2.AutoFunctionAOIWidth;
                    img_height=app.src_cam2.AutoFunctionAOIHeight;
                    app.UIAxes_Cam2.XLim = [0,img_width];                    
                    app.UIAxes_Cam2.YLim = [0,img_height];                    
                    app.UIAxes_Cam2.XTick = [];                    
                    app.UIAxes_Cam2.YTick = [];
                    pbaspect(app.UIAxes_Cam2,[img_width,img_height,1])
%                     pause(2)                    
%                     hImage2 = image(zeros(size(st2)), 'Parent',app.UIAxes_Cam2);
%                     preview(app.vid_cam2, hImage2);
%                     drawnow;
%                      start(app.vid_cam2)
                    st2=getsnapshot(app.vid_cam2);
                    imshow(st2,'parent',app.UIAxes_Cam2);                  
%                     delete(vid_top)
                    app.Cam2Lamp.Color = [00,1.00,0.00] ;
                    app.DisconnectedLabel_2.Text = 'Connected';                  
                case 'Off'
                    st2 = zeros(2048,2592);
                    imshow(st2,'parent',app.UIAxes_Cam2);
                    app.Cam2Lamp.Color = [0.65,0.65,0.65];
                     app.DisconnectedLabel_2.Text = 'Disconnected'; 
%                      stoppreview(app.vid_cam2);
            end
        end

        % Value changed function: Switch_Cam3
        function Switch_Cam3ValueChanged(app, event)
              global st3
            Value = app.Switch_Cam3.Value;
            switch Value
                case 'On'
%                     app.Cam1Lamp.Color = [0.5 0.5 0.5];
%                     vid_top = videoinput('gentl', 1, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');                  
                    % These lines set proper aspect ratio
                    img_width=app.src_cam3.AutoFunctionAOIWidth;
                    img_height=app.src_cam3.AutoFunctionAOIHeight;
                    app.UIAxes_Cam3.XLim = [0,img_width];                    
                    app.UIAxes_Cam3.YLim = [0,img_height];                    
                    app.UIAxes_Cam3.XTick = [];                    
                    app.UIAxes_Cam3.YTick = [];
                    pbaspect(app.UIAxes_Cam3,[img_width,img_height,1])
%                     pause(2)                    
%                     hImage3 = image(zeros(size(st3)), 'Parent',app.UIAxes_Cam2);
%                     preview(app.vid_cam2, hImage3);
%                     drawnow;
%                      start(app.vid_cam3)
                    st3=getsnapshot(app.vid_cam3);
                    imshow(st3,'parent',app.UIAxes_Cam3);                  
%                     delete(vid_top)
                    app.Cam3Lamp.Color = [00,1.00,0.00] ;
                    app.Disconnect3Label_3.Text = 'Connected';                  
                case 'Off'
                    st3 = zeros(2048,2592);
                    imshow(st3,'parent',app.UIAxes_Cam3);
                    app.Cam3Lamp.Color = [0.65,0.65,0.65];
                     app.Disconnect3Label_3.Text = 'Disconnected'; 
%                      stoppreview(app.vid_cam2);
            end
        end

        % Value changed function: Switch_Cam4
        function Switch_Cam4ValueChanged(app, event)
              global st4
            Value = app.Switch_Cam4.Value;
            switch Value
                case 'On'
%                     app.Cam1Lamp.Color = [0.5 0.5 0.5];
%                     vid_top = videoinput('gentl', 1, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');                  
                    % These lines set proper aspect ratio
                    img_width=app.src_cam4.AutoFunctionAOIWidth;
                    img_height=app.src_cam4.AutoFunctionAOIHeight;
                    app.UIAxes_Cam4.XLim = [0,img_width];                    
                    app.UIAxes_Cam4.YLim = [0,img_height];                    
                    app.UIAxes_Cam4.XTick = [];                    
                    app.UIAxes_Cam4.YTick = [];
                    pbaspect(app.UIAxes_Cam4,[img_width,img_height,1])
%                     pause(2)                    
%                     hImage3 = image(zeros(size(st3)), 'Parent',app.UIAxes_Cam2);
%                     preview(app.vid_cam2, hImage3);
%                     drawnow;
%                      start(app.vid_cam3)
                    st4=getsnapshot(app.vid_cam4);
                    imshow(st4,'parent',app.UIAxes_Cam4);                  
%                     delete(vid_top)
                    app.Cam4Lamp.Color = [00,1.00,0.00] ;
                    app.Disconnect4Label_4.Text = 'Connected';                  
                case 'Off'
                    st4 = zeros(2048,2592);
                    imshow(st4,'parent',app.UIAxes_Cam4);
                    app.Cam4Lamp.Color = [0.65,0.65,0.65];
                     app.Disconnect4Label_4.Text = 'Disconnected'; 
%                      stoppreview(app.vid_cam2);
            end
        end

        % Value changed function: Switch_Cam5
        function Switch_Cam5ValueChanged(app, event)
             global st5
            Value = app.Switch_Cam5.Value;
            switch Value
                case 'On'
%                     app.Cam1Lamp.Color = [0.5 0.5 0.5];
%                     vid_top = videoinput('gentl', 1, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');                  
                    % These lines set proper aspect ratio
                    img_width=app.src_cam5.AutoFunctionAOIWidth;
                    img_height=app.src_cam5.AutoFunctionAOIHeight;
                    app.UIAxes_Cam5.XLim = [0,img_width];                    
                    app.UIAxes_Cam5.YLim = [0,img_height];                    
                    app.UIAxes_Cam5.XTick = [];                    
                    app.UIAxes_Cam5.YTick = [];
                    pbaspect(app.UIAxes_Cam5,[img_width,img_height,1])
%                     pause(2)                    
%                     hImage3 = image(zeros(size(st3)), 'Parent',app.UIAxes_Cam2);
%                     preview(app.vid_cam2, hImage3);
%                     drawnow;
%                      start(app.vid_cam3)
                    st5=getsnapshot(app.vid_cam5);
                    imshow(st5,'parent',app.UIAxes_Cam5);                  
%                     delete(vid_top)
                    app.Cam5Lamp.Color = [00,1.00,0.00] ;
                    app.Disconnected5Label_5.Text = 'Connected';                  
                case 'Off'
                    st5 = zeros(2048,2592);
                    imshow(st5,'parent',app.UIAxes_Cam5);
                    app.Cam5Lamp.Color = [0.65,0.65,0.65];
                     app.Disconnect5Label_5.Text = 'Disconnected'; 
%                      stoppreview(app.vid_cam2);
            end
        end

        % Value changed function: Switch_Cam6
        function Switch_Cam6ValueChanged(app, event)
              global st6
            Value = app.Switch_Cam6.Value;
            switch Value
                case 'On'
%                     app.Cam1Lamp.Color = [0.5 0.5 0.5];
%                     vid_top = videoinput('gentl', 1, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');                  
                    % These lines set proper aspect ratio
                    img_width=app.src_cam6.AutoFunctionAOIWidth;
                    img_height=app.src_cam6.AutoFunctionAOIHeight;
                    app.UIAxes_Cam6.XLim = [0,img_width];                    
                    app.UIAxes_Cam6.YLim = [0,img_height];                    
                    app.UIAxes_Cam6.XTick = [];                    
                    app.UIAxes_Cam6.YTick = [];
                    pbaspect(app.UIAxes_Cam6,[img_width,img_height,1])
%                     pause(2)                    
%                     hImage3 = image(zeros(size(st3)), 'Parent',app.UIAxes_Cam2);
%                     preview(app.vid_cam2, hImage3);
%                     drawnow;
%                      start(app.vid_cam3)
                    st6=getsnapshot(app.vid_cam6);
                    imshow(st6,'parent',app.UIAxes_Cam6);                  
%                     delete(vid_top)
                    app.Cam6Lamp.Color = [00,1.00,0.00] ;
                    app.Disconnected6Label_6.Text = 'Connected';                  
                case 'Off'
                    st6 = zeros(2048,2592);
                    imshow(st6,'parent',app.UIAxes_Cam6);
                    app.Cam6Lamp.Color = [0.65,0.65,0.65];
                     app.Disconnected6Label_6.Text = 'Disconnected'; 
%                      stoppreview(app.vid_cam2);
            end
            
        end

        % Value changed function: Switch_Cam7
        function Switch_Cam7ValueChanged(app, event)
            global st7
            Value = app.Switch_Cam7.Value;
            switch Value
                case 'On'
%                     app.Cam1Lamp.Color = [0.5 0.5 0.5];
%                     vid_top = videoinput('gentl', 1, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');                  
                    % These lines set proper aspect ratio
                    img_width=app.src_cam7.AutoFunctionAOIWidth;
                    img_height=app.src_cam7.AutoFunctionAOIHeight;
                    app.UIAxes_Cam7.XLim = [0,img_width];                    
                    app.UIAxes_Cam7.YLim = [0,img_height];                    
                    app.UIAxes_Cam7.XTick = [];                    
                    app.UIAxes_Cam7.YTick = [];
                    pbaspect(app.UIAxes_Cam7,[img_width,img_height,1])
%                     pause(2)                    
%                     hImage3 = image(zeros(size(st3)), 'Parent',app.UIAxes_Cam2);
%                     preview(app.vid_cam2, hImage3);
%                     drawnow;
%                      start(app.vid_cam3)
                    st7=getsnapshot(app.vid_cam7);
                    imshow(st7,'parent',app.UIAxes_Cam7);                  
%                     delete(vid_top)
                    app.Cam7Lamp.Color = [00,1.00,0.00] ;
                    app.Disconnected7Label_7.Text = 'Connected';                  
                case 'Off'
                    st7 = zeros(2048,2592);
                    imshow(st7,'parent',app.UIAxes_Cam7);
                    app.Cam7Lamp.Color = [0.65,0.65,0.65];
                     app.Disconnect7Label_7.Text = 'Disconnected'; 
%                      stoppreview(app.vid_cam2);
            end            
        end

        % Button pushed function: LocationButton
        function LocationButtonPushed(app, event)
            dname = uigetdir('F:\Appdesigner\MatlabSample\');
            app.FolderEditField.Value=dname;
%             [file,path,indx] = uigetfile('E:\phenotyping_data\2020\rgb\');
%                 if isequal(file,0)
%                    disp('User selected Cancel')
%                 else
%                    disp(['User selected ', fullfile(path, file),... 
%                          ' and filter index: ', num2str(indx)])
%                 end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
             global st1 st2 st3 st4 st5 st6 st7           
            Value = app.Button_2.Value;
            path=app.FolderEditField.Value;
            data_name=app.File_NameEditField.Value;
            data_number=app.Number_SEditField.Value+1;         
            data_label1=strcat(path,'\',data_name,'_',num2str(data_number),'_Cam1','.jpg');
            data_label2=strcat(path,'\',data_name,'_',num2str(data_number),'_Cam2','.jpg');
            data_label3=strcat(path,'\',data_name,'_',num2str(data_number),'_Cam3','.jpg');
            data_label4=strcat(path,'\',data_name,'_',num2str(data_number),'_Cam4','.jpg');
            data_label5=strcat(path,'\',data_name,'_',num2str(data_number),'_Cam5','.jpg');
            data_label6=strcat(path,'\',data_name,'_',num2str(data_number),'_Cam6','.jpg');
            data_label7=strcat(path,'\',data_name,'_',num2str(data_number),'_Cam7','.jpg');        
            img_width=app.src_cam1.AutoFunctionAOIWidth;
            img_height=app.src_cam1.AutoFunctionAOIHeight;
            st1=zeros(img_width,img_height,3);
             st2=st1;
             st3=st1;
             st4=st1;
             st5=st1;
             st6=st1;
             st7=st1;
            st1=getsnapshot(app.vid_cam1);
            st2=getsnapshot(app.vid_cam2);  
            st3=getsnapshot(app.vid_cam3);
            st4=getsnapshot(app.vid_cam4);
            st5=getsnapshot(app.vid_cam5);
            st6=getsnapshot(app.vid_cam6);
            st7=getsnapshot(app.vid_cam7);            
            imwrite(st1,data_label1);
            imwrite(st2,data_label2);
            imwrite(st3,data_label3);
            imwrite(st4,data_label4);
            imwrite(st5,data_label5);
            imwrite(st6,data_label6);
            imwrite(st7,data_label7);
            imshow(st1,'parent',app.UIAxes_Cam1);
            imshow(st2,'parent',app.UIAxes_Cam2);
            imshow(st3,'parent',app.UIAxes_Cam3);
            imshow(st4,'parent',app.UIAxes_Cam4);
            imshow(st5,'parent',app.UIAxes_Cam5);
            imshow(st6,'parent',app.UIAxes_Cam6);
            imshow(st6,'parent',app.UIAxes_Cam7);
            app.Number_SEditField.Value=data_number;
             app.Button_2.Value=0
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectionStatusPanel = uipanel(app.UIFigure);
            app.ConnectionStatusPanel.Title = 'Connection Status ';
            app.Switch_Cam1 = uiswitch(app.ConnectionStatusPanel, 'slider');
            app.Switch_Cam1.ValueChangedFcn = createCallbackFcn(app, @Switch_Cam1ValueChanged, true);
            app.Cam1Lamp = uilamp(app.ConnectionStatusPanel);
            app.DisconnectedLabel = uilabel(app.ConnectionStatusPanel);
            app.DisconnectedLabel.Text = 'Disconnected';
            app.Switch_Cam2 = uiswitch(app.ConnectionStatusPanel, 'slider');
            app.Switch_Cam2.ValueChangedFcn = createCallbackFcn(app, @Switch_Cam2ValueChanged, true);
            app.Cam2Lamp = uilamp(app.ConnectionStatusPanel);
            app.DisconnectedLabel_2 = uilabel(app.ConnectionStatusPanel);
            app.DisconnectedLabel_2.Text = 'Disconnected';
            app.Switch_Cam3 = uiswitch(app.ConnectionStatusPanel, 'slider');
            app.Switch_Cam3.ValueChangedFcn = createCallbackFcn(app, @Switch_Cam3ValueChanged, true);
            app.Cam3Lamp = uilamp(app.ConnectionStatusPanel);
            app.Switch_Cam4 = uiswitch(app.ConnectionStatusPanel, 'slider');
            app.Switch_Cam4.ValueChangedFcn = createCallbackFcn(app, @Switch_Cam4ValueChanged, true);
            app.Cam4Lamp = uilamp(app.ConnectionStatusPanel);
            app.Disconnect3Label_3 = uilabel(app.ConnectionStatusPanel);
            app.Disconnect3Label_3.Text = 'Disconnedted';
            app.Disconnect4Label_4 = uilabel(app.ConnectionStatusPanel);
            app.Disconnect4Label_4.Text = 'Disconnected';
            app.Switch_Cam5 = uiswitch(app.ConnectionStatusPanel, 'slider');
            app.Switch_Cam5.ValueChangedFcn = createCallbackFcn(app, @Switch_Cam5ValueChanged, true);
            app.Disconnected5Label_5 = uilabel(app.ConnectionStatusPanel);
            app.Disconnected5Label_5.Text = 'Disconnected';
            app.Cam5Lamp = uilamp(app.ConnectionStatusPanel);
            app.Switch_Cam6 = uiswitch(app.ConnectionStatusPanel, 'slider');
            app.Switch_Cam6.ValueChangedFcn = createCallbackFcn(app, @Switch_Cam6ValueChanged, true);
            app.Disconnected6Label_6 = uilabel(app.ConnectionStatusPanel);
            app.Disconnected6Label_6.Text = 'Disconnected';
            app.Cam6Lamp = uilamp(app.ConnectionStatusPanel);
            app.Switch_Cam7 = uiswitch(app.ConnectionStatusPanel, 'slider');
            app.Switch_Cam7.ValueChangedFcn = createCallbackFcn(app, @Switch_Cam7ValueChanged, true);
            app.Disconnected7Label_7 = uilabel(app.ConnectionStatusPanel);
            app.Disconnected7Label_7.Text = 'Disconnected';
            app.Cam7Lamp = uilamp(app.ConnectionStatusPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RGBPreviewTab = uitab(app.TabGroup);
            app.RGBPreviewTab.Title = 'RGB Preview';
            app.ExposureEditFieldLabel = uilabel(app.RGBPreviewTab);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.RGBPreviewTab, 'numeric');
            app.ColorCorrectionButton = uibutton(app.RGBPreviewTab, 'push');
            app.ColorCorrectionButton.Text = 'Color Correction';
            app.UIAxes_Cam1 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam1, 'Cam1')
            xlabel(app.UIAxes_Cam1, 'X')
            ylabel(app.UIAxes_Cam1, 'Y')
            zlabel(app.UIAxes_Cam1, 'Z')
            app.UIAxes_Cam2 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam2, 'Cam2')
            xlabel(app.UIAxes_Cam2, 'X')
            ylabel(app.UIAxes_Cam2, 'Y')
            zlabel(app.UIAxes_Cam2, 'Z')
            app.UIAxes_Cam3 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam3, 'Cam3')
            xlabel(app.UIAxes_Cam3, 'X')
            ylabel(app.UIAxes_Cam3, 'Y')
            zlabel(app.UIAxes_Cam3, 'Z')
            app.UIAxes_Cam4 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam4, 'Cam4')
            xlabel(app.UIAxes_Cam4, 'X')
            ylabel(app.UIAxes_Cam4, 'Y')
            zlabel(app.UIAxes_Cam4, 'Z')
            app.UIAxes_Cam5 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam5, 'Cam5')
            xlabel(app.UIAxes_Cam5, 'X')
            ylabel(app.UIAxes_Cam5, 'Y')
            zlabel(app.UIAxes_Cam5, 'Z')
            app.UIAxes_Cam6 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam6, 'Cam6')
            xlabel(app.UIAxes_Cam6, 'X')
            ylabel(app.UIAxes_Cam6, 'Y')
            zlabel(app.UIAxes_Cam6, 'Z')
            app.UIAxes_Cam7 = uiaxes(app.RGBPreviewTab);
            title(app.UIAxes_Cam7, 'Cam7')
            xlabel(app.UIAxes_Cam7, 'X')
            ylabel(app.UIAxes_Cam7, 'Y')
            zlabel(app.UIAxes_Cam7, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'atitle.PNG';
            app.CapturingImagePanel = uipanel(app.UIFigure);
            app.CapturingImagePanel.Title = 'Capturing Image';
            app.FolderEditFieldLabel = uilabel(app.CapturingImagePanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.CapturingImagePanel, 'text');
            app.LocationButton = uibutton(app.CapturingImagePanel, 'push');
            app.LocationButton.ButtonPushedFcn = createCallbackFcn(app, @LocationButtonPushed, true);
            app.LocationButton.Icon = 'folder.PNG';
            app.LocationButton.Text = 'Location';
            app.File_NameEditFieldLabel = uilabel(app.CapturingImagePanel);
            app.File_NameEditFieldLabel.Text = 'File_Name';
            app.File_NameEditField = uieditfield(app.CapturingImagePanel, 'text');
            app.File_NameEditField.Value = 'test';
            app.Number_SEditFieldLabel = uilabel(app.CapturingImagePanel);
            app.Number_SEditFieldLabel.Text = 'Number_S';
            app.Number_SEditField = uieditfield(app.CapturingImagePanel, 'numeric');
            app.Button_2 = uibutton(app.CapturingImagePanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Icon = 'OkB.PNG';
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HTPP3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end