classdef HTPP_System < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsDisplayUIFigure         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Panel2                          matlab.ui.container.Panel
        PortSelectionDropDownLabel      matlab.ui.control.Label
        PortSelectionDropDown           matlab.ui.control.DropDown
        HIS_JAEWONSwitchLabel           matlab.ui.control.Label
        HIS_JAEWONSwitch                matlab.ui.control.Switch
        Jaewon_MLampLabel               matlab.ui.control.Label
        Jaewon_MLamp                    matlab.ui.control.Lamp
        PortSelectionDropDown_2Label    matlab.ui.control.Label
        PortSelectionDropDown_2         matlab.ui.control.DropDown
        PlantTransportSwitchLabel       matlab.ui.control.Label
        PlantTransportSwitch            matlab.ui.control.Switch
        PT_MLampLabel                   matlab.ui.control.Label
        PT_MLamp                        matlab.ui.control.Lamp
        Panel2_4                        matlab.ui.container.Panel
        CameraSelectionDropDownLabel    matlab.ui.control.Label
        CameraSelectionDropDown         matlab.ui.control.DropDown
        Headwall_CamLampLabel           matlab.ui.control.Label
        Headwall_CamLamp                matlab.ui.control.Lamp
        Contect_HButton                 matlab.ui.control.StateButton
        RGB_CameraPanel                 matlab.ui.container.Panel
        Top_Cam_SelectionDropDownLabel  matlab.ui.control.Label
        Top_Cam_SelectionDropDown       matlab.ui.control.DropDown
        FL_Cam_SelectionDropDownLabel   matlab.ui.control.Label
        FL_Cam_SelectionDropDown        matlab.ui.control.DropDown
        Active_TopLampLabel             matlab.ui.control.Label
        Active_TopLamp                  matlab.ui.control.Lamp
        Active_FLLampLabel              matlab.ui.control.Label
        Active_FLLamp                   matlab.ui.control.Lamp
        Switch_TOP                      matlab.ui.control.Switch
        Switch_FL                       matlab.ui.control.Switch
        Image                           matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        RGBPreviweTab                   matlab.ui.container.Tab
        Capture_RGBButton               matlab.ui.control.StateButton
        Call_PathWayButton              matlab.ui.control.Button
        PathEditFieldLabel              matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        File_NameEditField_2Label       matlab.ui.control.Label
        File_NameEditField_2            matlab.ui.control.EditField
        Sample_NumberEditFieldLabel     matlab.ui.control.Label
        Sample_NumberEditField          matlab.ui.control.NumericEditField
        UIAxes_Top                      matlab.ui.control.UIAxes
        UIAxes_FL                       matlab.ui.control.UIAxes
        HSIPreviewTab                   matlab.ui.container.Tab
        SpeedmmsEditFieldLabel          matlab.ui.control.Label
        SpeedmmsEditField               matlab.ui.control.NumericEditField
        Sample_PositionmmEditFieldLabel  matlab.ui.control.Label
        Sample_PositionmmEditField      matlab.ui.control.NumericEditField
        GoButton                        matlab.ui.control.StateButton
        Curunt_PositionEditFieldLabel   matlab.ui.control.Label
        Curunt_PositionEditField        matlab.ui.control.NumericEditField
        StartingPoint1000mmEndPointLabel  matlab.ui.control.Label
        SetButton                       matlab.ui.control.Button
        Set_Zero_PostionLabel           matlab.ui.control.Label
        StopButton_2                    matlab.ui.control.Button
        ExposuretimeEditField_2Label    matlab.ui.control.Label
        ExposuretimeEditField_2         matlab.ui.control.NumericEditField
        GainEditField_2Label            matlab.ui.control.Label
        GainEditField_2                 matlab.ui.control.NumericEditField
        HSIPreviewButton                matlab.ui.control.StateButton
        UIAxes_HSI_preview              matlab.ui.control.UIAxes
        CaptureOptionTab                matlab.ui.container.Tab
        StartingPointmmEditFieldLabel   matlab.ui.control.Label
        StartingPointmmEditField        matlab.ui.control.NumericEditField
        FinishPointmmEditFieldLabel     matlab.ui.control.Label
        FinishPointmmEditField          matlab.ui.control.NumericEditField
        OneStepsizemmEditFieldLabel     matlab.ui.control.Label
        OneStepsizemmEditField          matlab.ui.control.NumericEditField
        TotalStepsEditFieldLabel        matlab.ui.control.Label
        TotalStepsEditField             matlab.ui.control.NumericEditField
        Capture_SpeedmmsEditFieldLabel  matlab.ui.control.Label
        Capture_SpeedmmsEditField       matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ShowingOptionforCapturescreenPanel  matlab.ui.container.Panel
        Bandselection1273bands352956nmButtonGroup  matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Band2Green510nm71bandEditFieldLabel  matlab.ui.control.Label
        Band2Green510nm71bandEditField  matlab.ui.control.NumericEditField
        Band3Blue450nm44bandEditFieldLabel  matlab.ui.control.Label
        Band3Blue450nm44bandEditField   matlab.ui.control.NumericEditField
        Band1Red710nm161bandEditFieldLabel  matlab.ui.control.Label
        Band1Red710nm161bandEditField   matlab.ui.control.NumericEditField
        CoefficientselectionButtonGroup  matlab.ui.container.ButtonGroup
        Button_2                        matlab.ui.control.RadioButton
        FieldofviewmmEditFieldLabel     matlab.ui.control.Label
        FieldofviewmmEditField          matlab.ui.control.NumericEditField
        SpartalResolutionEditFieldLabel  matlab.ui.control.Label
        SpartalResolutionEditField      matlab.ui.control.NumericEditField
        FramePeriodmsEditFieldLabel     matlab.ui.control.Label
        FramePeriodmsEditField          matlab.ui.control.NumericEditField
        SCANButton                      matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        FileLabelEditFieldLabel         matlab.ui.control.Label
        FileLabelEditField              matlab.ui.control.NumericEditField
        UIAxes_HIS_scan                 matlab.ui.control.UIAxes
        ClassificationTab               matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        RGBResultPanel                  matlab.ui.container.Panel
        SubstiblityLampLabel            matlab.ui.control.Label
        SubstiblityLamp                 matlab.ui.control.Lamp
        ResistanceLampLabel             matlab.ui.control.Label
        ResistanceLamp                  matlab.ui.control.Lamp
        HSIResultPanel                  matlab.ui.container.Panel
        SubstiblityLamp_2Label          matlab.ui.control.Label
        SubstiblityLamp_2               matlab.ui.control.Lamp
        ResistanceLamp_2Label           matlab.ui.control.Label
        ResistanceLamp_2                matlab.ui.control.Lamp
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        UIAxes14                        matlab.ui.control.UIAxes
        UIAxes15                        matlab.ui.control.UIAxes
        Room3OnButton                   matlab.ui.control.StateButton
        Room4OnButton                   matlab.ui.control.StateButton
        RGBOnButton                     matlab.ui.control.StateButton
        HSIOnButton                     matlab.ui.control.StateButton
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
        vid_top % share of rgb data 
        src_top %
        vid_FL
        src_FL        
        pt
    end
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins
            binwidth = app.BinWidth;
            range2plot =  floor(min(data)):binwidth:ceil(max(data));
            NBins = size(range2plot,2);
        end
        
        function annotateScatterPlot(app)
            % Update X and Y Labels
            app.UIAxes_all.XLabel.String = 'Weight';
            app.UIAxes_all.YLabel.String = app.BloodPressureSwitch.Value;
            % Dont show the histogram slider
            app.BinWidthSliderLabel.Visible = 'off';
            app.BinWidthSlider.Visible = 'off';
        end
        
        function annotateHistogram(app)
           
            % Update X and Y Labels
            app.UIAxes_all.XLabel.String = app.BloodPressureSwitch.Value;
            app.UIAxes_all.YLabel.String = '# of Patients';
            
            % Show histogram slider
            app.BinWidthSliderLabel.Visible = 'on';
            app.BinWidthSlider.Visible = 'on';
        end
        
        function filterData(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the smoker control
            if app.NoCheckBox.Value && ~app.YesCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 0];
            elseif app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 1];
            elseif  ~app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the gender control
            if app.MaleCheckBox.Value && ~app.FemaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Male"];
            elseif app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Female"];
            elseif  ~app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the location control
            if app.CameraSelectionDropDown.Value ~= "All"
                tempIndices = [tempIndices, app.Data.Location == string(app.CameraSelectionDropDown.Value)];
            end
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             % Load the data.
%             load('patients.mat','LastName','Gender','Smoker','Age','Height','Weight','Diastolic','Systolic','Location');
%             
%             % Store the data in a table and display it in one of the App's tabs.
%             app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
%             app.UITable.Data = app.Data;
%             app.BinWidth = app.BinWidthSlider.Value;
%             
%             % Update the axes with the corresponding data.
%             updateSelectedGenders(app)
%             refreshplot(app)
               app.Headwall_CamLamp.Color = [0.5 0.5 0.5];
               app.Jaewon_MLamp.Color = [0.5 0.5 0.5];
               app.Active_TopLamp.Color = [0.5 0.5 0.5];
               app.Active_FLLamp.Color = [0.5 0.5 0.5];
               app.vid_top = videoinput('gentl', 1, 'RGB8Packed');
               app.src_top = getselectedsource(app.vid_top);
               app.src_top.BinningHorizontal = 'BinningHorizontal2';
               app.src_top.BinningVertical = 'BinningVertical2';
               app.vid_top.FramesPerTrigger = 4;               
               triggerconfig(app.vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');
               
               app.vid_FL = videoinput('gentl', 2, 'RGB8Packed');
               app.src_FL = getselectedsource(app.vid_FL);
               app.src_FL.BinningHorizontal = 'BinningHorizontal2';
               app.src_FL.BinningVertical = 'BinningVertical2';
               app.vid_FL.FramesPerTrigger = 4;
               triggerconfig(app.vid_FL, 'hardware', 'DeviceSpecific', 'DeviceSpecific');   
               
               app.pt=serialport("COM7",9600,'DataBits',8,'parity','none','stopbits',1,'FlowControl','software');
               configureTerminator(app.pt,"CR")              
%                app.Active_TopLamp.Color = [0.5 0.5 0.5 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {796, 796};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Callback function
        function refreshplot(app, event)
            Genders = app.SelectedGenders;
            Colors = app.SelectedColors;
            
            % Start with a fresh plot
            cla(app.UIAxes_all)
            hold(app.UIAxes_all,'on')
            app.Histogram = gobjects(0);
            
            % Select relevant segment of data
            xdata = app.Data.Weight;
            ydata = app.Data.(app.BloodPressureSwitch.Value);
            
            % Filter the data according to the controls
            filterData(app);
            
            % Create either a scatter plot or histogram, based on selection
            switch app.Bandselection1273bands352956nmButtonGroup.SelectedObject.Text
                
                case 'Scatter'
                    % Build a scatter plot for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        scatter(app.UIAxes_all,xdata((selectedpatients)),ydata(selectedpatients),Colors{ii});
                    end
                    annotateScatterPlot(app)
                    
                case 'Histogram'
                    % Build a histogram for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        NBins = numhistbins(app,ydata(selectedpatients));
                        h = histogram(app.UIAxes_all,ydata(selectedpatients),NBins,'BinLimits',[floor(min(ydata)) ceil(max(ydata))]);
                        h.EdgeColor = Colors{ii};
                        h.FaceColor = Colors{ii};
                        app.Histogram = [app.Histogram h];
                    end
                    annotateHistogram(app)
                    
            end
            
            % Update the table to show only the data that satisfies the controls
            app.UITable.Data = app.Data(app.displayedIndices,:);
            drawnow;
        end

        % Value changed function: CameraSelectionDropDown
        function updateSelectedGenders(app, event)
            % List which genders and colors to use
            Genders = [];
            Colors = [];
            Smoker = [];
            
            if app.MaleCheckBox.Value
                Genders = "Male";
                Colors = "blue";
            end
            if app.FemaleCheckBox.Value
                Genders = [Genders "Female"];
                Colors = [Colors "red"];
            end
            if app.YesCheckBox.Value
                Smoker = "Yes";
            end
            if app.NoCheckBox.Value
                Smoker = [Smoker "No"];
            end
            
            if isempty(Genders) || isempty(Smoker)
                % Disable the switches and buttons if they were on
                app.BloodPressureSwitch.Enable = 'off';
                app.ScatterButton.Enable = 'off';
                app.HistogramButton.Enable = 'off';
                app.BinWidthSliderLabel.Enable = 'off';
                app.BinWidthSlider.Enable = 'off';
            else
                % Enable the switches and buttons if they were off
                app.BloodPressureSwitch.Enable = 'on';
                app.ScatterButton.Enable = 'on';
                app.HistogramButton.Enable = 'on';
                app.BinWidthSliderLabel.Enable = 'on';
                app.BinWidthSlider.Enable = 'on';
            end
            app.SelectedGenders = Genders;
            app.SelectedColors = Colors;
            
            refreshplot(app)
        end

        % Callback function
        function ShowButtonPushed(app, event)

            
        end

        % Callback function
        function Switch3ValueChanged(app, event)
            value = app.Switch_FR.Value

        end

        % Value changed function: Contect_HButton
        function Contect_HButtonValueChanged(app, event)
            value = app.Contect_HButton.Value;
            cd C:\Headwall\HSIII64
            %% Find the Install Path for HSIII
            % this is the default install location for HSIII.
            hs3installPath = 'C:\Headwall\HSIII64';
            % Check to see if the path exists
            pathCheck = exist(hs3installPath,'dir');
            % Check the results of 'exist', a value other than 7 means the path does
            % not exist
            if pathCheck ~= 7
                % Check the windows registry for the install location of HSIII
                hs3installPath = winqueryreg('Location','HKEY_LOCAL_MACHINE','SOFTWARE\Headwall Photonics Inc.\HyperSpec III');
                % Registry give the base path for HSIII and not the location of the
                % libraries.
                hs3installPath = [hs3installPath, '\HSIII64'];
                % Add HSIII64 to the base path, as that is the location that has the
                % libraries. Then check to see if that path exists.
                if (exist(hs3installPath,'dir') ~= 7 )
                    % If the path stored in hs3installPath does not exist on the hard
                    % drive then,
                    warndlg('Unable to find HyperSpec III install path. Click "Ok" to select the HSIII64 directory location.');
                    % throw a warning to the user and ask the user to select the
                    % directory/folder that HSIII is installed in.
                    hs3installPath = uigetdir;
                    if hs3installPath == 0
                        % If no directory is selected
                        disp('No folder/directory was selected.');
                        disp('This function will not set HyperSpec III(R) directory for this current session of MatLab.');
                        disp('Exiting function.');
                        return;
                    end
                    % Check to make sure that the path the user selected is valid and
                    % exists.
                    if (exist(hs3installPath,'dir') ~= 7 )
                        % If the directory/folder is not valid or does not exist then
                        % exit and throw an error.
                        error('Selected directory does not exist. Please select an existing directory.');
                    end
                end
            end
            % Now the path to HSIII should be contained in hs3installPath.
            
            %% Set Enviromental Paths to QT DLLs
            % Set the QT_PLUGIN_PATH variable.
            qtPluginPath = getenv('QT_PLUGIN_PATH');
            % check to see if the path has already been set
            if ~strcmpi(qtPluginPath,hs3installPath)
                % merge paths, if there is any path to being with.
                setenv('QT_PLUGIN_PATH', [getenv('QT_PLUGIN_PATH') [';' hs3installPath]]);
            end
            % Set the QT_QPA_PLATFORM_PLUGIN_PATH variable.
            hs3installPathPlugin = [hs3installPath,'\platforms'];
            qtPlatformPluginPath = getenv('QT_QPA_PLATFORM_PLUGIN_PATH');
            % check to see if the path has already been set
            if ~strcmpi(qtPlatformPluginPath,hs3installPathPlugin)
                % merge paths, if there is any path to being with.
                setenv('QT_QPA_PLATFORM_PLUGIN_PATH',[getenv('QT_QPA_PLATFORM_PLUGIN_PATH') [';' hs3installPathPlugin] ]);
            end
                        
            loadlibrary('hpiSdk','hsSdk.h')
            libfunctions('hpiSdk')
            H_value=calllib('hpiSdk','HS_startEnv','C:\Headwall\HSIII64\vars.ini','C:\Headwall\HSIII64\config.json')
            
            if H_value==-1
                app.Headwall_CamLamp.Color=[0.5 0.5 0.5];
                msgbox('Headwall Cam is not conected !! Please try again !!', 'Error','error');
            elseif H_value==1
                app.Headwall_CamLamp.Color=[0 1 0];
            end

%                 case 'Off'
%             end % end switch
        end

        % Callback function
        function Stop_HButtonValueChanged(app, event)
            value = app.Stop_HButton.Value;
%             calllib('hpiSdk','HS_stopEnv')
            unloadlibrary('hpiSdk')

        end

        % Callback function
        function ShowButtonValueChanged(app, event)
            
            if app.Headwall_CamLamp.Color == [0 1 0]
                
                calllib('hpiSdk','HS_setExposure',0,app.ExposuretimeEditField.Value);
                calllib('hpiSdk','HS_getExposure',0)
                value = app.ShowButton.Value;
    %                 for i=1:10
                while  app.ShowButton.Value == 1
                    data = calllib('hpiSdk','HS_grabData',0);
                    setdatatype(data.value.rawBuffer,'uint16Ptr',data.value.columns,data.value.rows);
%                     plot(app.UIAxes_all,data.value.rawBuffer);
%                     localData = rot90(data.value.rawBuffer,1);
%                     plot(app.UIAxes_all,localData);
                    imshow(data.value.rawBuffer',[],'parent',app.UIAxes_all);
                    drawnow
                    pause(0.01)
                    if app.ShowButton.Value == 0
                        break
                    end
                end
            else app.Headwall_CamLamp.Color == [0.5 0.5 0.5]
                msgbox('Not Conected Headwall HSI !!','Error','error')
            end            
        end

        % Callback function
        function M_ContectButtonValueChanged(app, event)
            
        end

        % Value changed function: PortSelectionDropDown
        function PortSelectionDropDownValueChanged(app, event)
            value = app.PortSelectionDropDown.Value;
            
        end

        % Callback function
        function M_Dis_ContectButtonValueChanged(app, event)
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            calllib('EziMOTIONPlusRx64','FAS_SetActualPos',int32(PortN), int32(SlaveN),0)
        end

        % Value changed function: GoButton
        function GoButtonValueChanged(app, event)
            value = app.GoButton.Value;
            SlaveN=0;
            lVelocity=app.SpeedmmsEditField.Value*2000
            abs_point=app.Sample_PositionmmEditField.Value*2000
            calllib('EziMOTIONPlusRx64','FAS_MoveSingleAxisAbsPos',int32(str2num(app.PortSelectionDropDown.Value)), int32(SlaveN),abs_point,int32(lVelocity))
        end

        % Value changed function: Sample_PositionmmEditField
        function Sample_PositionmmEditFieldValueChanged(app, event)
            value = app.Sample_PositionmmEditField.Value;
            
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            SlaveN=0;
            calllib('EziMOTIONPlusRx64','FAS_MoveStop',int32(str2num(app.PortSelectionDropDown.Value)), int32(SlaveN))
        end

        % Value changed function: HIS_JAEWONSwitch
        function HIS_JAEWONSwitchValueChanged(app, event)
            value = app.HIS_JAEWONSwitch.Value;
            switch app.HIS_JAEWONSwitch.Value
                case 'On'
                    app.Jaewon_MLamp.Color = [0.5 0.5 0.5]
                    Baudrate=115200;
                    SlaveN=0; % Moter number
                     
%                     ServoS=1; %on=1 off=0
        
                    loadlibrary('EziMOTIONPlusRx64', @FAS_EziMOTIONPlusR); 
                    libfunctions('EziMOTIONPlusRx64');
                    ServoS=calllib('EziMOTIONPlusRx64', 'FAS_Connect',int32(str2num(app.PortSelectionDropDown.Value)), int32(Baudrate))
                    
                    if ServoS == 1
                        calllib('EziMOTIONPlusRx64', 'FAS_ServoEnable', int32(str2num(app.PortSelectionDropDown.Value)), int32(SlaveN), int32(ServoS))
%                                               
                        fprintf('Servo On!');
                        app.Jaewon_MLamp.Color = [0 1 0] ;
                    
                    elseif ServoS == 0                        
%                         msgbox('Plese Check USB Connection', 'Error','error');
                        msgbox('Plese Check Port Number', 'Error','error');
                    end
                    
                case 'Off'
                    ServoS=0;
                    SlaveN=0;
                    calllib('EziMOTIONPlusRx64', 'FAS_ServoEnable', int32(str2num(app.PortSelectionDropDown.Value)), int32(SlaveN), int32(ServoS))
                    calllib('EziMOTIONPlusRx64','FAS_Close',int32(str2num(app.PortSelectionDropDown.Value)))% off
                    fprintf('Servo Off!');
                    app.Jaewon_MLamp.Color = [0.5 0.5 0.5];
            end
        end

        % Value changed function: SpeedmmsEditField
        function SpeedmmsEditFieldValueChanged(app, event)
            value = app.SpeedmmsEditField.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            onestepsize=app.FieldofviewmmEditField.Value/app.SpartalResolutionEditField.Value;    %fov/r;
            total=(app.FinishPointmmEditField.Value-app.StartingPointmmEditField.Value)/onestepsize; %(fin-str)/onestepsize;
            speed=(app.FinishPointmmEditField.Value-app.StartingPointmmEditField.Value)/(app.FramePeriodmsEditField.Value/1000*total);  %(fin-str)/(fp*1000*total)
            app.OneStepsizemmEditField.Value=onestepsize;
            app.TotalStepsEditField.Value=total;
            app.Capture_SpeedmmsEditField.Value=round(speed,3);
        end

        % Callback function
        function Contect_RButtonPushed(app, event)
            
            vid = videoinput('gige', 1, 'Mono14');
            src = getselectedsource(vid);
            vid.FramesPerTrigger = 1;
        end

        % Callback function
        function Contect_RButtonValueChanged(app, event)
            value = app.Contect_RButton.Value;
            if value == 1
                app.Stop_RButton.event=0;
            else
                app.Stop_RButton.Value=1;
            end
%             vid = videoinput('gige', 1, 'Mono14');
%             src = getselectedsource(vid);
%             vid.FramesPerTrigger = 1;          
            
        end

        % Callback function
        function Contect_RButtonPushed2(app, event)
            vid = videoinput('gentl', 1, 'Mono8');
            src = getselectedsource(vid);
%             vid.FramesPerTrigger = 1;
            R_value=vid.type;
            
            if R_value == 'videoinput'
                app.Resonon_CamLamp.Color = [0 1 0] ;
            end
            
        end

        % Callback function
        function Stop_RButtonPushed(app, event)
            clear app.Contect_RButtonPushed2.vid app.Contect_RButtonPushed2.srd app.Contect_RButtonPushed2.R_value
            app.Resonon_CamLamp.Color = [0.5 0.5 0.5] ;
            
        end

        % Callback function
        function gogoButtonPushed(app, event)
            if app.Headwall_CamLamp.Color == [0 1 0]
                
                calllib('hpiSdk','HS_setExposure',0,app.ExposuretimeEditField.Value);
                calllib('hpiSdk','HS_getExposure',0)
                value = app.ShowButton.Value;
    %                 for i=1:10
                while  app.ShowButton.Value == 1
                    data = calllib('hpiSdk','HS_grabData',0);
                    setdatatype(data.value.rawBuffer,'uint16Ptr',data.value.columns,data.value.rows);
%                     plot(app.UIAxes_all,data.value.rawBuffer);
%                     localData = rot90(data.value.rawBuffer,1);
%                     plot(app.UIAxes_all,localData);
                    imshow(data.value.rawBuffer',[],'parent',app.UIAxes_all);
                    drawnow
                    pause(0.01)
                    if app.ShowButton.Value == 0
                        break
                    end
                end
                
            elseif app.Resonon_CamLamp.Color == [0 1 0]
                vid = videoinput('gentl', 1, 'Mono8');
                src = getselectedsource(vid);
                src.ExposureTime=app.ExposuretimeEditField.Value*1000;
                src.BinningHorizontal=2;
                src.BinningVertical=2;
                data_size=vid.ROIPosition;
                data_size=data_size(1,3:4);
                data=zeros([data_size]);
                spectral=1:1:data_size(1,2); % uint16spatial
                spatial=1:1:data_size(1,1);
                spatial_center=floor(data_size(1,2)/2);
                spectral_center=floor(data_size(1,1)/2);
                axis(app.UIAxes_spatial,[0 data_size(1,1) 0 500]);
                axis(app.UIAxes_spectral,[0 500 0 data_size(1,2)]);
                
                p_spa=plot(spatial,spatial,'parent',app.UIAxes_spatial);
                p_spe=plot(spectral,spectral,'parent',app.UIAxes_spectral);
    %                 for i=1:10
                trigger_gogo=1;
                while  trigger_gogo == 1
                    data =getsnapshot(vid);
                    imshow(data,'parent',app.UIAxes_all);
                    line_spatial = data(spatial_center,:);
                    line_spectral = data(:,spectral_center);
                    
%                     temp=app.UIAxes_all.CurrentPoint;
%                     loc=[temp(1,1) temp(1,2)];
%                     if (loc(1,1)<=0) || (loc(1,1) > data_size(1,2))
%                         line_spatial = data(floor(data_size(1,2)/2),:);
%                     else
%                         line_spatial = data(floor(loc(1,1)),:);                        
%                     end
%                     if (loc(1,2)<=0) || (loc(1,2) > data_size(1,1))
%                         line_spectral = data(:,floor(data_size(1,1)/2));
% %                         tt = (data(:,floor(data_size(1,2)/2)));
%                     else
%                         line_spectral = data(:,floor(loc(1,2)));                        
%                     end
                    set(p_spa,'XData',spatial,'YData',line_spatial);                                      
                    set(p_spe,'XData',line_spectral,'YData',spectral);
        
                    drawnow
%                     pause(0.01)
%                     if app.stoppButton.Value == 1
%                         trigger_gogo=0;
%                     end
                end
            end
        end

        % Callback function
        function stoppButtonPushed(app, event)
            app.gogoButtonPushed.trigger_gogo=0;
        end

        % Value changed function: Switch_TOP
        function Switch_TOPValueChanged(app, event)
            value = app.Switch_TOP.Value;
            switch app.Switch_TOP.Value
                case 'On'
%                     app.Active_TopLamp.Color = [0.5 0.5 0.5];
%                     vid_top = videoinput('gentl', 1, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');
                   
                    % These lines set proper aspect ratio
                    img_width=app.src_top.AutoFunctionAOIWidth;
                    img_height=app.src_top.AutoFunctionAOIHeight;
                    app.UIAxes_Top.XLim = [0,img_width];                    
                    app.UIAxes_Top.YLim = [0,img_height];                    
                    app.UIAxes_Top.XTick = [];                    
                    app.UIAxes_Top.YTick = [];
                    pbaspect(app.UIAxes_Top,[img_width,img_height,1])
                    
                    pause(2)                   
                    
%                     preview(app.vid_top);
                    start(app.vid_top)
                    st=getsnapshot(app.vid_top);
                    imshow(st,'parent',app.UIAxes_Top);
                    
%                     delete(vid_top)
                    app.Active_TopLamp.Color = [0 1 0] ;
                    
                case 'Off'
                    st=zeros(2048,2592);
                    imshow(st,'parent',app.UIAxes_Top);
                    app.Active_TopLamp.Color = [0.5 0.5 0.5];
            end
        end

        % Callback function
        function RGBRreviweSwitchValueChanged(app, event)
%             value = app.RGBRreviweSwitch.Value;
            switch app.RGBRreviweSwitch.Value
                case 'On'
%                     vid_top = videoinput('gentl', 2, 'RGB8Packed');
%                     src_top = getselectedsource(vid_top);
%                     vid_top.FramesPerTrigger = 1;
%                     triggerconfig(vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');
% %                   
%                     app.UIAxes_Top.XLim = [0,2592];                    
%                     app.UIAxes_Top.YLim = [0,2048];                    
%                     app.UIAxes_Top.XTick = [];                    
%                     app.UIAxes_Top.YTick = [];                    
%                     
% %                     preview(vid_top,app.UIAxes_Top)
%                     start(vid_top)
%                     st=getsnapshot(vid_top);
%                     imshow(st,'parent',app.UIAxes_Top);
% %                     delete(vid_top)
% 
%                     while  app.RGBRreviweSwitch.Value == 'On'
%                         start(vid_top)
%                         st=getsnapshot(vid_top);
%                         imshow(st,'parent',app.UIAxes_Top);
%                         drawnow
%                         
%                         pause(0.01)
% %                         if app.Switch_TOP.Value == 'Off'
% %                             break
% %                         end
%                     end

                case 'Off'
                    delet(vid_top)
            end
        end

        % Callback function
        function Button2Pushed(app, event)
%             top=uint8();
            getselectedsource(app.vid_top);
            app.vid_top.FramesPerTrigger = 1;
            triggerconfig(app.vid_top, 'hardware', 'DeviceSpecific', 'DeviceSpecific');
            pause(2)
            preview(app.vid_top)

        end

        % Value changed function: Switch_FL
        function Switch_FLValueChanged(app, event)
            switch app.Switch_FL.Value
                case 'On'
                    img_width=app.src_FL.AutoFunctionAOIWidth;
                    img_height=app.src_FL.AutoFunctionAOIHeight;
                    app.UIAxes_FL.XLim = [0,img_width];                    
                    app.UIAxes_FL.YLim = [0,img_height];                    
                    app.UIAxes_FL.XTick = [];                    
                    app.UIAxes_FL.YTick = [];
                    pbaspect(app.UIAxes_FL,[img_width,img_height,1])         
                    pause(2) 
%                     preview(app.vid_FL);
                    start(app.vid_FL)
                    st=getsnapshot(app.vid_FL);
                    imshow(st,'parent',app.UIAxes_FL);
                    app.Active_FLLamp.Color = [0 1 0] ; 
                case 'Off'
                    st=zeros(2048,2592);
                    imshow(st,'parent',app.UIAxes_FL);
                    app.Active_FLLamp.Color = [0.5 0.5 0.5];
            end
                    
        end

        % Callback function
        function Button2Pushed2(app, event)
            preview(app.vid_top);
        end

        % Button pushed function: Call_PathWayButton
        function Call_PathWayButtonPushed3(app, event)
            dname = uigetdir('E:\phenotyping_data\2020\rgb\');
            app.PathEditField.Value=dname;
%             [file,path,indx] = uigetfile('E:\phenotyping_data\2020\rgb\');
%                 if isequal(file,0)
%                    disp('User selected Cancel')
%                 else
%                    disp(['User selected ', fullfile(path, file),... 
%                          ' and filter index: ', num2str(indx)])
%                 end
        end

        % Value changed function: Capture_RGBButton
        function Capture_RGBButtonValueChanged(app, event)
            value = app.Capture_RGBButton.Value
            path=app.PathEditField.Value;
            data_name=app.File_NameEditField_2.Value;
            data_number=app.Sample_NumberEditField.Value+1;
            data_label1=strcat(path,'\',data_name,'_',num2str(data_number),'_top','.jpg');
            data_label2=strcat(path,'\',data_name,'_',num2str(data_number),'_FL','.jpg');
%             start(app.vid_top)
            img_width=app.src_top.AutoFunctionAOIWidth;
            img_height=app.src_top.AutoFunctionAOIHeight;
            st1=zeros(img_width,img_height,3);
            st2=st1;
            st1=getsnapshot(app.vid_top);
            st2=getsnapshot(app.vid_FL);
            imwrite(st1,data_label1)
            imwrite(st2,data_label2)
            app.Sample_NumberEditField.Value=data_number;
            imshow(st1,'parent',app.UIAxes_Top);
            imshow(st2,'parent',app.UIAxes_FL);           
            app.Capture_RGBButton.Value=0;
            writeline(app.pt,"WWRA0#013");
            
        end

        % Value changed function: HSIPreviewButton
        function HSIPreviewButtonValueChanged(app, event)
            global k
            value = app.HSIPreviewButton.Value;
            calllib('hpiSdk','HS_setFramePeriod',0,3);% set frame period to least value (3ms)
            
            if app.Headwall_CamLamp.Color == [0 1 0]
                calllib('hpiSdk','HS_setExposure',0,app.ExposuretimeEditField_2.Value);
                app.ExposuretimeEditField_2.Value = calllib('hpiSdk','HS_getExposure',0);
                
                HSI_data=uint16(zeros(273,640));
                
                k = 1;
    %                 for i=1:10
                while  (app.HSIPreviewButton.Value == 1)&&(k==1)
                    data = calllib('hpiSdk','HS_grabData',0);
%                     save('data.mat', 'data');
                    setdatatype(data.value.rawBuffer,'uint16Ptr',data.value.columns,data.value.rows);
%                     plot(app.UIAxes_all,data.value.rawBuffer);
%                     localData = rot90(data.value.rawBuffer,1);
%                     plot(app.UIAxes_all,localData);
                    HSI_data=data.value.rawBuffer';
                    imshow(HSI_data,[],'parent',app.UIAxes_HSI_preview);
                    drawnow
%                     pause(0.01)
                    if app.HSIPreviewButton.Value == 0
                        break
                        k = 0;
                    end
                end
            else app.Headwall_CamLamp.Color == [0.5 0.5 0.5]
                msgbox('Not Conected Headwall HSI !!','Error','error')
            end  
        end

        % Button pushed function: SCANButton
        function SCANButtonPushed(app, event)
            if app.Headwall_CamLamp.Color == [0 1 0]
                
                calllib('hpiSdk','HS_setExposure',0,app.ExposuretimeEditField_2.Value);
                calllib('hpiSdk','HS_getExposure',0)
                path='E:\phenotyping_data\2020\vis';
                data_name=app.FileNameEditField.Value;
                data_number=app.FileLabelEditField.Value+1;
                app.FileLabelEditField.Value=data_number;
                total_name=strcat(path,'\',data_name,'_',num2str(data_number),'.bil');
                HSI_data=zeros(640,273);
                HSI_data2=zeros(640,app.TotalStepsEditField.Value);
                SlaveN=0;
                lVelocity=app.Capture_SpeedmmsEditField.Value*2000;
                abs_point=app.OneStepsizemmEditField.Value*2000;
                abs_zero=0;
%                 abs_point=app.FinishPointmmEditField.Value*2000;
%                 calllib('EziMOTIONPlusRx64','FAS_MoveSingleAxisAbsPos',int32(str2num(app.PortSelectionDropDown.Value)), int32(SlaveN),abs_point,int32(lVelocity))
                
                f = waitbar(0,'Processing...');
%                 total_steps=
                all_d=zeros(app.TotalStepsEditField.Value,640,273);
%                 
%                 for i=1:40
%                     calllib('EziMOTIONPlusRx64','FAS_MoveSingleAxisAbsPos',int32(str2num(app.PortSelectionDropDown.Value)), int32(SlaveN),abs_zero,int32(lVelocity));
%                     data = calllib('hpiSdk','HS_grabData',0);
%                     setdatatype(data.value.rawBuffer,'uint16Ptr',data.value.columns,data.value.rows);
%                     HSI_data(:,:)=(data.value.rawBuffer);
%                     waitbar(i/(app.TotalStepsEditField.Value+40),f,'Buffer cleaning...')
%                 end
                
                
                for i=1:app.TotalStepsEditField.Value
                    calllib('EziMOTIONPlusRx64','FAS_MoveSingleAxisAbsPos',int32(str2num(app.PortSelectionDropDown.Value)), int32(SlaveN),abs_zero,int32(lVelocity));
                    data = calllib('hpiSdk','HS_grabData',0);
                    setdatatype(data.value.rawBuffer,'uint16Ptr',data.value.columns,data.value.rows);
                    HSI_data(:,:)=(data.value.rawBuffer);
                    all_d(i,1:640,1:273)=reshape(HSI_data,[1 640 273]);
                    abs_zero=abs_zero+abs_point;                    
                    waitbar((i+40)/(app.TotalStepsEditField.Value+40),f,'HSI Scanning...')
                    disp(num2str(i))
                    pause(0.005)
                end
                delete(f)
                pause(0.02)
                abs_point = 0;
                calllib('EziMOTIONPlusRx64','FAS_MoveSingleAxisAbsPos',int32(str2num(app.PortSelectionDropDown.Value)), int32(SlaveN),abs_point,int32(lVelocity*10));
                r=rescale(all_d(:,:,app.Band1Red710nm161bandEditField.Value),0,1);
                g=rescale(all_d(:,:,app.Band2Green510nm71bandEditField.Value),0,1);
                b=rescale(all_d(:,:,app.Band3Blue450nm44bandEditField.Value),0,1);
                band3=cat(3,r,g,b);
                imshow(band3,'parent',app.UIAxes_HIS_scan)
                drawnow
                multibandwrite(all_d, total_name, 'bil');                
                clear all_d
                writeline(app.pt,"WWRB0#013");
                save('hsi_data.mat','all_d');
            else app.Headwall_CamLamp.Color == [0.5 0.5 0.5]
                msgbox('Not Conected Headwall HSI !!','Error','error')
            end  
        end

        % Value changed function: FileLabelEditField
        function FileLabelEditFieldValueChanged(app, event)
            value = app.FileLabelEditField.Value;
            
        end

        % Value changed function: Room3OnButton
        function Room3OnButtonValueChanged(app, event)
            value = app.Room3OnButton.Value;
            writeline(app.pt,"WWRC0#013");
        end

        % Value changed function: Room4OnButton
        function Room4OnButtonValueChanged(app, event)
            value = app.Room4OnButton.Value;
            writeline(app.pt,"WWRD0#013");
        end

        % Value changed function: RGBOnButton
        function RGBOnButtonValueChanged(app, event)
            value = app.RGBOnButton.Value;
            writeline(app.pt,"WWRA0#013");
        end

        % Value changed function: HSIOnButton
        function HSIOnButtonValueChanged(app, event)
            value = app.HSIOnButton.Value;
            writeline(app.pt,"WWRB0#013");
        end

        % Value changed function: PlantTransportSwitch
        function PlantTransportSwitchValueChanged(app, event)
            value = app.PlantTransportSwitch.Value;
            switch app.PlantTransportSwitch.Value
                case 'On'
                    writeline(app.pt,"WWR90#013");
                    app.PT_MLamp.Color = [0 1 0];                    
                case 'Off'
                    writeline(app.pt,"WWR00#013");
                    app.PT_MLamp.Color = [0.5 0.5 0.5];
            end
        end

        % Value changed function: ExposuretimeEditField_2
        function ExposuretimeEditField_2ValueChanged(app, event)
            global k
            
            try
                if k == 1
                    was_live = 1;
                else
                    was_live = 0;
                end
            catch
                was_live = 0;
            end
            %stop preview
            k = 0;
            drawnow;
            new_exp_value = app.ExposuretimeEditField_2.Value;
            calllib('hpiSdk','HS_setFramePeriod',0,3);% set frame period to least value (3ms)
            calllib('hpiSdk','HS_setExposure',0,new_exp_value);% set exposure to new value
            pause(0.1)
            
            % restart preview
            if was_live
                k=1;
                try
                    HSIPreviewButtonValueChanged(app, event)
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsDisplayUIFigure = uifigure('Visible', 'off');
            app.PatientsDisplayUIFigure.Name = 'Patients Display';
            app.PatientsDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2 = uipanel(app.LeftPanel);
            app.Panel2.Title = 'Moter Conection';
            app.PortSelectionDropDownLabel = uilabel(app.Panel2);
            app.PortSelectionDropDownLabel.Text = 'Port Selection';
            app.PortSelectionDropDown = uidropdown(app.Panel2);
            app.PortSelectionDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.PortSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @PortSelectionDropDownValueChanged, true);
            app.PortSelectionDropDown.Value = '8';
            app.HIS_JAEWONSwitchLabel = uilabel(app.Panel2);
            app.HIS_JAEWONSwitchLabel.Text = 'HIS_JAEWON';
            app.HIS_JAEWONSwitch = uiswitch(app.Panel2, 'slider');
            app.HIS_JAEWONSwitch.ValueChangedFcn = createCallbackFcn(app, @HIS_JAEWONSwitchValueChanged, true);
            app.Jaewon_MLampLabel = uilabel(app.Panel2);
            app.Jaewon_MLampLabel.Text = 'Jaewon_M';
            app.Jaewon_MLamp = uilamp(app.Panel2);
            app.PortSelectionDropDown_2Label = uilabel(app.Panel2);
            app.PortSelectionDropDown_2Label.Text = 'Port Selection';
            app.PortSelectionDropDown_2 = uidropdown(app.Panel2);
            app.PortSelectionDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.PortSelectionDropDown_2.Value = '7';
            app.PlantTransportSwitchLabel = uilabel(app.Panel2);
            app.PlantTransportSwitchLabel.Text = 'Plant Transport';
            app.PlantTransportSwitch = uiswitch(app.Panel2, 'slider');
            app.PlantTransportSwitch.ValueChangedFcn = createCallbackFcn(app, @PlantTransportSwitchValueChanged, true);
            app.PT_MLampLabel = uilabel(app.Panel2);
            app.PT_MLampLabel.Text = 'PT_M';
            app.PT_MLamp = uilamp(app.Panel2);
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Hyperspectal_Camera';
            app.CameraSelectionDropDownLabel = uilabel(app.Panel2_4);
            app.CameraSelectionDropDownLabel.Text = 'Camera Selection';
            app.CameraSelectionDropDown = uidropdown(app.Panel2_4);
            app.CameraSelectionDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8'};
            app.CameraSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.CameraSelectionDropDown.Value = '0';
            app.Headwall_CamLampLabel = uilabel(app.Panel2_4);
            app.Headwall_CamLampLabel.Text = 'Headwall_Cam';
            app.Headwall_CamLamp = uilamp(app.Panel2_4);
            app.Contect_HButton = uibutton(app.Panel2_4, 'state');
            app.Contect_HButton.ValueChangedFcn = createCallbackFcn(app, @Contect_HButtonValueChanged, true);
            app.Contect_HButton.Text = 'Contect_H';
            app.RGB_CameraPanel = uipanel(app.LeftPanel);
            app.RGB_CameraPanel.Title = 'RGB_Camera';
            app.Top_Cam_SelectionDropDownLabel = uilabel(app.RGB_CameraPanel);
            app.Top_Cam_SelectionDropDownLabel.Text = 'Top_Cam_Selection';
            app.Top_Cam_SelectionDropDown = uidropdown(app.RGB_CameraPanel);
            app.FL_Cam_SelectionDropDownLabel = uilabel(app.RGB_CameraPanel);
            app.FL_Cam_SelectionDropDownLabel.Text = 'FL_Cam_Selection';
            app.FL_Cam_SelectionDropDown = uidropdown(app.RGB_CameraPanel);
            app.Active_TopLampLabel = uilabel(app.RGB_CameraPanel);
            app.Active_TopLampLabel.Text = 'Active_Top';
            app.Active_TopLamp = uilamp(app.RGB_CameraPanel);
            app.Active_FLLampLabel = uilabel(app.RGB_CameraPanel);
            app.Active_FLLampLabel.Text = 'Active_FL';
            app.Active_FLLamp = uilamp(app.RGB_CameraPanel);
            app.Switch_TOP = uiswitch(app.RGB_CameraPanel, 'slider');
            app.Switch_TOP.ValueChangedFcn = createCallbackFcn(app, @Switch_TOPValueChanged, true);
            app.Switch_FL = uiswitch(app.RGB_CameraPanel, 'slider');
            app.Switch_FL.ValueChangedFcn = createCallbackFcn(app, @Switch_FLValueChanged, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'atitle.PNG';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.RGBPreviweTab = uitab(app.TabGroup);
            app.RGBPreviweTab.Title = 'RGB Previwe';
            app.Capture_RGBButton = uibutton(app.RGBPreviweTab, 'state');
            app.Capture_RGBButton.ValueChangedFcn = createCallbackFcn(app, @Capture_RGBButtonValueChanged, true);
            app.Capture_RGBButton.Text = 'Capture_RGB';
            app.Call_PathWayButton = uibutton(app.RGBPreviweTab, 'push');
            app.Call_PathWayButton.ButtonPushedFcn = createCallbackFcn(app, @Call_PathWayButtonPushed3, true);
            app.Call_PathWayButton.Text = 'Call_PathWay';
            app.PathEditFieldLabel = uilabel(app.RGBPreviweTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.RGBPreviweTab, 'text');
            app.PathEditField.Value = 'E:\phenotyping_data\2020\rgb';
            app.File_NameEditField_2Label = uilabel(app.RGBPreviweTab);
            app.File_NameEditField_2Label.Text = 'File_Name';
            app.File_NameEditField_2 = uieditfield(app.RGBPreviweTab, 'text');
            app.File_NameEditField_2.Value = 'test';
            app.Sample_NumberEditFieldLabel = uilabel(app.RGBPreviweTab);
            app.Sample_NumberEditFieldLabel.Text = 'Sample_Number';
            app.Sample_NumberEditField = uieditfield(app.RGBPreviweTab, 'numeric');
            app.UIAxes_Top = uiaxes(app.RGBPreviweTab);
            title(app.UIAxes_Top, 'Top Cam')
            zlabel(app.UIAxes_Top, 'Z')
            app.UIAxes_FL = uiaxes(app.RGBPreviweTab);
            title(app.UIAxes_FL, 'Front of Left Cam')
            zlabel(app.UIAxes_FL, 'Z')
            app.HSIPreviewTab = uitab(app.TabGroup);
            app.HSIPreviewTab.Title = 'HSI Preview';
            app.SpeedmmsEditFieldLabel = uilabel(app.HSIPreviewTab);
            app.SpeedmmsEditFieldLabel.Text = 'Speed (mm/s)';
            app.SpeedmmsEditField = uieditfield(app.HSIPreviewTab, 'numeric');
            app.SpeedmmsEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedmmsEditFieldValueChanged, true);
            app.SpeedmmsEditField.Value = 10;
            app.Sample_PositionmmEditFieldLabel = uilabel(app.HSIPreviewTab);
            app.Sample_PositionmmEditFieldLabel.Text = 'Sample_Position (mm)';
            app.Sample_PositionmmEditField = uieditfield(app.HSIPreviewTab, 'numeric');
            app.Sample_PositionmmEditField.ValueChangedFcn = createCallbackFcn(app, @Sample_PositionmmEditFieldValueChanged, true);
            app.GoButton = uibutton(app.HSIPreviewTab, 'state');
            app.GoButton.ValueChangedFcn = createCallbackFcn(app, @GoButtonValueChanged, true);
            app.GoButton.Text = 'Go';
            app.Curunt_PositionEditFieldLabel = uilabel(app.HSIPreviewTab);
            app.Curunt_PositionEditFieldLabel.Text = 'Curunt_Position';
            app.Curunt_PositionEditField = uieditfield(app.HSIPreviewTab, 'numeric');
            app.StartingPoint1000mmEndPointLabel = uilabel(app.HSIPreviewTab);
            app.StartingPoint1000mmEndPointLabel.Text = '0 (Starting Point) ~ 1000 mm (End Point)';
            app.SetButton = uibutton(app.HSIPreviewTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Set_Zero_PostionLabel = uilabel(app.HSIPreviewTab);
            app.Set_Zero_PostionLabel.Text = 'Set_Zero_Postion';
            app.StopButton_2 = uibutton(app.HSIPreviewTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.ExposuretimeEditField_2Label = uilabel(app.HSIPreviewTab);
            app.ExposuretimeEditField_2Label.Text = 'Exposure time';
            app.ExposuretimeEditField_2 = uieditfield(app.HSIPreviewTab, 'numeric');
            app.ExposuretimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @ExposuretimeEditField_2ValueChanged, true);
            app.ExposuretimeEditField_2.Value = 40;
            app.GainEditField_2Label = uilabel(app.HSIPreviewTab);
            app.GainEditField_2Label.Text = 'Gain';
            app.GainEditField_2 = uieditfield(app.HSIPreviewTab, 'numeric');
            app.HSIPreviewButton = uibutton(app.HSIPreviewTab, 'state');
            app.HSIPreviewButton.ValueChangedFcn = createCallbackFcn(app, @HSIPreviewButtonValueChanged, true);
            app.HSIPreviewButton.Text = 'HSI Preview';
            app.UIAxes_HSI_preview = uiaxes(app.HSIPreviewTab);
            title(app.UIAxes_HSI_preview, 'Hyperspectral Cam Preview')
            xlabel(app.UIAxes_HSI_preview, 'X')
            ylabel(app.UIAxes_HSI_preview, 'Y')
            zlabel(app.UIAxes_HSI_preview, 'Z')
            app.CaptureOptionTab = uitab(app.TabGroup);
            app.CaptureOptionTab.Title = 'Capture Option';
            app.StartingPointmmEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.StartingPointmmEditFieldLabel.Text = 'Starting Point (mm)';
            app.StartingPointmmEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.FinishPointmmEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.FinishPointmmEditFieldLabel.Text = 'Finish Point (mm)';
            app.FinishPointmmEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.FinishPointmmEditField.Value = 140;
            app.OneStepsizemmEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.OneStepsizemmEditFieldLabel.Text = 'One Step size (mm)';
            app.OneStepsizemmEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.TotalStepsEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.TotalStepsEditFieldLabel.Text = 'Total Steps';
            app.TotalStepsEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.Capture_SpeedmmsEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.Capture_SpeedmmsEditFieldLabel.Text = 'Capture_Speed (mm/s)';
            app.Capture_SpeedmmsEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.CalculateButton = uibutton(app.CaptureOptionTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SaveButton = uibutton(app.CaptureOptionTab, 'push');
            app.SaveButton.Text = 'Save';
            app.ShowingOptionforCapturescreenPanel = uipanel(app.CaptureOptionTab);
            app.ShowingOptionforCapturescreenPanel.Title = 'Showing Option for Capture screen';
            app.Bandselection1273bands352956nmButtonGroup = uibuttongroup(app.ShowingOptionforCapturescreenPanel);
            app.Bandselection1273bands352956nmButtonGroup.Title = '3 Band selection(1~273 bands, 352 ~ 956 nm)';
            app.Button = uiradiobutton(app.Bandselection1273bands352956nmButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Band2Green510nm71bandEditFieldLabel = uilabel(app.Bandselection1273bands352956nmButtonGroup);
            app.Band2Green510nm71bandEditFieldLabel.Text = 'Band 2 (Green : 510 nm, 71 band ) ';
            app.Band2Green510nm71bandEditField = uieditfield(app.Bandselection1273bands352956nmButtonGroup, 'numeric');
            app.Band2Green510nm71bandEditField.Value = 71;
            app.Band3Blue450nm44bandEditFieldLabel = uilabel(app.Bandselection1273bands352956nmButtonGroup);
            app.Band3Blue450nm44bandEditFieldLabel.Text = 'Band 3 (Blue : 450 nm, 44 band) ';
            app.Band3Blue450nm44bandEditField = uieditfield(app.Bandselection1273bands352956nmButtonGroup, 'numeric');
            app.Band3Blue450nm44bandEditField.Value = 44;
            app.Band1Red710nm161bandEditFieldLabel = uilabel(app.Bandselection1273bands352956nmButtonGroup);
            app.Band1Red710nm161bandEditFieldLabel.Text = 'Band 1 (Red : 710 nm, 161 band) ';
            app.Band1Red710nm161bandEditField = uieditfield(app.Bandselection1273bands352956nmButtonGroup, 'numeric');
            app.Band1Red710nm161bandEditField.Value = 161;
            app.CoefficientselectionButtonGroup = uibuttongroup(app.ShowingOptionforCapturescreenPanel);
            app.CoefficientselectionButtonGroup.Title = 'Coefficient selection';
            app.Button_2 = uiradiobutton(app.CoefficientselectionButtonGroup);
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.FieldofviewmmEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.FieldofviewmmEditFieldLabel.Text = 'Field of view (mm)';
            app.FieldofviewmmEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.FieldofviewmmEditField.Value = 140;
            app.SpartalResolutionEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.SpartalResolutionEditFieldLabel.Text = 'Spartal Resolution';
            app.SpartalResolutionEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.SpartalResolutionEditField.Value = 640;
            app.FramePeriodmsEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.FramePeriodmsEditFieldLabel.Text = 'Frame Period (ms)';
            app.FramePeriodmsEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.FramePeriodmsEditField.Value = 40;
            app.SCANButton = uibutton(app.CaptureOptionTab, 'push');
            app.SCANButton.ButtonPushedFcn = createCallbackFcn(app, @SCANButtonPushed, true);
            app.SCANButton.Text = 'SCAN';
            app.FileNameEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.CaptureOptionTab, 'text');
            app.FileNameEditField.Value = 'test';
            app.FileLabelEditFieldLabel = uilabel(app.CaptureOptionTab);
            app.FileLabelEditFieldLabel.Text = 'File Label';
            app.FileLabelEditField = uieditfield(app.CaptureOptionTab, 'numeric');
            app.FileLabelEditField.ValueChangedFcn = createCallbackFcn(app, @FileLabelEditFieldValueChanged, true);
            app.UIAxes_HIS_scan = uiaxes(app.CaptureOptionTab);
            title(app.UIAxes_HIS_scan, 'Scan Result')
            zlabel(app.UIAxes_HIS_scan, 'Z')
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.Panel = uipanel(app.ClassificationTab);
            app.Panel.Title = 'Panel';
            app.RGBResultPanel = uipanel(app.Panel);
            app.RGBResultPanel.Title = 'RGB Result';
            app.SubstiblityLampLabel = uilabel(app.RGBResultPanel);
            app.SubstiblityLampLabel.Text = 'Substiblity';
            app.SubstiblityLamp = uilamp(app.RGBResultPanel);
            app.ResistanceLampLabel = uilabel(app.RGBResultPanel);
            app.ResistanceLampLabel.Text = 'Resistance';
            app.ResistanceLamp = uilamp(app.RGBResultPanel);
            app.HSIResultPanel = uipanel(app.Panel);
            app.HSIResultPanel.Title = 'HSI Result';
            app.SubstiblityLamp_2Label = uilabel(app.HSIResultPanel);
            app.SubstiblityLamp_2Label.Text = 'Substiblity';
            app.SubstiblityLamp_2 = uilamp(app.HSIResultPanel);
            app.ResistanceLamp_2Label = uilabel(app.HSIResultPanel);
            app.ResistanceLamp_2Label.Text = 'Resistance';
            app.ResistanceLamp_2 = uilamp(app.HSIResultPanel);
            app.UIAxes10 = uiaxes(app.ClassificationTab);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.ClassificationTab);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes12 = uiaxes(app.ClassificationTab);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes14 = uiaxes(app.ClassificationTab);
            title(app.UIAxes14, 'Title')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.UIAxes15 = uiaxes(app.ClassificationTab);
            title(app.UIAxes15, 'Title')
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            zlabel(app.UIAxes15, 'Z')
            app.Room3OnButton = uibutton(app.RightPanel, 'state');
            app.Room3OnButton.ValueChangedFcn = createCallbackFcn(app, @Room3OnButtonValueChanged, true);
            app.Room3OnButton.Text = 'Room 3 On';
            app.Room4OnButton = uibutton(app.RightPanel, 'state');
            app.Room4OnButton.ValueChangedFcn = createCallbackFcn(app, @Room4OnButtonValueChanged, true);
            app.Room4OnButton.Text = 'Room 4 On';
            app.RGBOnButton = uibutton(app.RightPanel, 'state');
            app.RGBOnButton.ValueChangedFcn = createCallbackFcn(app, @RGBOnButtonValueChanged, true);
            app.RGBOnButton.Text = 'RGB On';
            app.HSIOnButton = uibutton(app.RightPanel, 'state');
            app.HSIOnButton.ValueChangedFcn = createCallbackFcn(app, @HSIOnButtonValueChanged, true);
            app.HSIOnButton.Text = 'HSI On';
            app.ContextMenu = uicontextmenu(app.PatientsDisplayUIFigure);
            % Assign app.ContextMenu
            app.Top_Cam_SelectionDropDown.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.PatientsDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HTPP_System

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsDisplayUIFigure)
        end
    end
end